classdef app_design < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        SimulationDropDown       matlab.ui.control.DropDown
        SimulationDropDownLabel  matlab.ui.control.Label
        title                    matlab.ui.control.Label
        ModulationDropDown       matlab.ui.control.DropDown
        Z_sumodelDropDown        matlab.ui.control.DropDown
        E_sumodelDropDown        matlab.ui.control.DropDown
        RunsimulationButton      matlab.ui.control.Button
        E_sumodelDropDownLabel   matlab.ui.control.Label
        Z_sumodelDropDownLabel   matlab.ui.control.Label
        ModulationDropDownLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, modulation_model, Z_su_model, E_su_model, simulation_type)
            app.ModulationDropDown.Items = modulation_model;
            app.Z_sumodelDropDown.Items = Z_su_model;
            app.E_sumodelDropDown.Items = E_su_model;
            app.SimulationDropDown.Items = simulation_type;
        end

        % Button pushed function: RunsimulationButton
        function RunsimulationButtonPushed(app, event)
            modulation_model = app.ModulationDropDown.Value;
            Z_su_model = app.Z_sumodelDropDown.Value;
            E_su_model = app.E_sumodelDropDown.Value;
            simulation_type = app.SimulationDropDown.Value;

            switch simulation_type
                case 'TM'
                    Analysis_TM(modulation_model, Z_su_model, E_su_model);
                otherwise
                    error(['Required simulation not found (' simulation_type ')'])
            end
        end

        % Callback function: not associated with a component
        function ExportButtonPushed(app, event)
            testFileName = app.ModulationDropDown.Value;
            controllerFileName = replace(app.Z_sumodelDropDown.Value, '*', 'star');
            filterDropDownFileName = replace(app.E_sumodelDropDown.Value, '*', 'star');
            
            filename = [testFileName '_' controllerFileName '_' filterDropDownFileName];

            export_pdf_graphic(gcf, ['/results/' filename]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ModulationDropDownLabel = uilabel(app.GridLayout);
            app.ModulationDropDownLabel.Text = 'Modulation';
            app.Z_sumodelDropDownLabel = uilabel(app.GridLayout);
            app.Z_sumodelDropDownLabel.Text = 'Z_su model';
            app.E_sumodelDropDownLabel = uilabel(app.GridLayout);
            app.E_sumodelDropDownLabel.Text = 'E_su model';
            app.RunsimulationButton = uibutton(app.GridLayout, 'push');
            app.RunsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunsimulationButtonPushed, true);
            app.RunsimulationButton.Text = 'Run simulation';
            app.E_sumodelDropDown = uidropdown(app.GridLayout);
            app.E_sumodelDropDown.Items = {};
            app.E_sumodelDropDown.Value = {};
            app.Z_sumodelDropDown = uidropdown(app.GridLayout);
            app.ModulationDropDown = uidropdown(app.GridLayout);
            app.title = uilabel(app.GridLayout);
            app.title.Text = 'MagLev results selector';
            app.SimulationDropDownLabel = uilabel(app.GridLayout);
            app.SimulationDropDownLabel.Text = 'Simulation';
            app.SimulationDropDown = uidropdown(app.GridLayout);
            app.SimulationDropDown.Items = {};
            app.SimulationDropDown.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_design(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end